classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AES_Encryption                 matlab.ui.Figure
        AESENCRYPTIONLabel             matlab.ui.control.Label
        DataTextAreaLabel              matlab.ui.control.Label
        DataTextArea                   matlab.ui.control.TextArea
        KeyTextAreaLabel               matlab.ui.control.Label
        KeyTextArea                    matlab.ui.control.TextArea
        AESTypeDropDown_2Label         matlab.ui.control.Label
        AESTypeDropDown                matlab.ui.control.DropDown
        EncryptButton                  matlab.ui.control.Button
        DecryptButton                  matlab.ui.control.Button
        KeyformatDropDownLabel         matlab.ui.control.Label
        KeyformatDropDown              matlab.ui.control.DropDown
        IVTextAreaLabel                matlab.ui.control.Label
        IVTextArea                     matlab.ui.control.TextArea
        Image                          matlab.ui.control.Image
        DataformatInputOutputDropDownLabel  matlab.ui.control.Label
        DataformatInputOutputDropDown  matlab.ui.control.DropDown
        AESModeDropDownLabel           matlab.ui.control.Label
        AESModeDropDown                matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            userData = app.DataTextArea.Value;
            keyInput = app.KeyTextArea.Value;
            keyType = app.KeyformatDropDown.Value;
            AESMode = app.AESModeDropDown.Value;
            AESType = app.AESTypeDropDown.Value;
            IVValue = app.IVTextArea.Value;
            dataFormat = app.DataformatInputOutputDropDown.Value;
            % Raise error dialog box if entered key is invalid
            if key_check(keyInput, keyType, AESType) || IV_check(IVValue) || data_check(userData, dataFormat, keyType, "encrypt")
                if key_check(keyInput, keyType, AESType)
                    msgbox(["    Invalid entered key. Check entered key";
                            "and whether the correct key format is selected."], "Invalid key");
                end
                if IV_check(IVValue)
                    msgbox(["                 Invalid entered IV. ";
                            "Format is 16 hex value, check entered value "], "Invalid IV");
                end
                if data_check(userData, dataFormat, keyType, "encrypt")
                    msgbox(["         Invalid entered data.";
                            "Encryption data requires an input" ], "Invalid data");
                end
            else
                cipher = main_encrypt(userData, dataFormat, keyInput, keyType, AESMode, AESType, IVValue);
                app.DataTextArea.Value = cipher;
            end
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            userData = app.DataTextArea.Value;
            keyInput = app.KeyTextArea.Value;
            keyType = app.KeyformatDropDown.Value;
            AESType = app.AESTypeDropDown.Value;
            AESMode = app.AESModeDropDown.Value;
            IVValue = app.IVTextArea.Value;
            dataFormat = app.DataformatInputOutputDropDown.Value;
            % Raise error dialog box if either key, data or IV scripts rais flags
            if key_check(keyInput, keyType, AESType) || IV_check(IVValue) || data_check(userData, "pass", keyType, "decrypt")
                if key_check(keyInput, keyType, AESType)
                    msgbox(["    Invalid entered key. Check entered key";
                            "and whether the correct key format is selected."], "Invalid key");
                end
                if IV_check(IVValue)
                    msgbox(["                 Invalid entered IV. ";
                            "Format is 16 hex value, check entered value "], "Invalid IV");
                end
                if data_check(userData, "pass", keyType, "decrypt")
                    msgbox(["        Invalid entered data. Decryption format is a";
                            " continuous hex value of length that is a multiple of 16" ], "Invalid data");
                end
            else
                plainttext = main_decrypt(userData, dataFormat, keyInput, keyType, AESMode, AESType, IVValue);
                app.DataTextArea.Value = plainttext;
            end
        end

        % Value changed function: KeyTextArea
        function KeyTextAreaValueChanged(app, event)

        end

        % Callback function
        function AESModeDropDownValueChanged(app, event)

        end

        % Value changed function: AESModeDropDown
        function AESModeDropDownValueChanged2(app, event)
            if app.AESModeDropDown.Value == "CBC"
                % Reveal/enable IV text and label if AES Type is CBC
                app.IVTextArea.Enable = 1;
                app.IVTextAreaLabel.Enable = 1;
            else
                % Hide/disable IV text and label if AES Type is ECB
                app.IVTextArea.Enable = 0;
                app.IVTextAreaLabel.Enable = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AES_Encryption = uifigure('Visible', 'off');
            app.AES_Encryption.Name = 'AES Encryption';
            app.AESENCRYPTIONLabel = uilabel(app.AES_Encryption);
            app.AESENCRYPTIONLabel.Text = 'AES ENCRYPTION';
            app.DataTextAreaLabel = uilabel(app.AES_Encryption);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.AES_Encryption);
            app.DataTextArea.Tooltip = {''};
            app.KeyTextAreaLabel = uilabel(app.AES_Encryption);
            app.KeyTextAreaLabel.Text = 'Key';
            app.KeyTextArea = uitextarea(app.AES_Encryption);
            app.KeyTextArea.ValueChangedFcn = createCallbackFcn(app, @KeyTextAreaValueChanged, true);
            app.KeyTextArea.Tooltip = {''};
            app.AESTypeDropDown_2Label = uilabel(app.AES_Encryption);
            app.AESTypeDropDown_2Label.Text = 'AES Type';
            app.AESTypeDropDown = uidropdown(app.AES_Encryption);
            app.AESTypeDropDown.Items = {'128-bit', '192-bit', '256-bit'};
            app.AESTypeDropDown.Value = '128-bit';
            app.EncryptButton = uibutton(app.AES_Encryption, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.DecryptButton = uibutton(app.AES_Encryption, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.KeyformatDropDownLabel = uilabel(app.AES_Encryption);
            app.KeyformatDropDownLabel.Text = 'Key format';
            app.KeyformatDropDown = uidropdown(app.AES_Encryption);
            app.KeyformatDropDown.Items = {'Plaintext', 'Hex'};
            app.KeyformatDropDown.Value = 'Plaintext';
            app.IVTextAreaLabel = uilabel(app.AES_Encryption);
            app.IVTextAreaLabel.Enable = 'off';
            app.IVTextAreaLabel.Text = 'IV';
            app.IVTextArea = uitextarea(app.AES_Encryption);
            app.IVTextArea.Enable = 'off';
            app.IVTextArea.Tooltip = {''};
            app.Image = uiimage(app.AES_Encryption);
            app.Image.Tooltip = {'Data: Decryption data has to be a continuous hex value of length that is a multiple of 16. Both uppercase and lowercase letters are accepted.'; ''; 'Key: Encryption and decryption keys can either be 128-bit, 192-bit or 256-bit (depending on the selected AES Mode). One has a choice of either entering the key in plaintext or hex. If plaintext, for 128-bit key user should enter 16 characters, for 192-bit 24 characters and for 256-bit 32 characters. For hex, 128-bit key user should enter a continuous 32 hex value, for 192-bit a continuous 48 hex value and for 256-bit a continuous 64 hex value. '; 'Examples:'; 'Plaintext 128-bit key: Thats my Kung Fu'; 'Hex 128-bit key:5468617473206D79204B756E67204675'; ''; 'Data format: Selection of the type of format data will be encrypted/decrypted. Can either be input and output in plaintext or hex '; ''; 'IV: Initialization vector requires a continuous 32 hex value when using AES Type CBC. If IV is unused leave text field blank. '; 'Example:'; '347117a91ddf95ab54037d299e32face'; ''; 'Recommendations: For encrypting information that is above 16 characters in length, it is suggested to use CBC instead of ECB to prevent leaking information. Whilst the user can choose not to use the IV for CBC, it is highly recommended a unique IV value be used for each encryption of data.'; ''; ''};
            app.Image.ImageSource = 'help icon.png';
            app.DataformatInputOutputDropDownLabel = uilabel(app.AES_Encryption);
            app.DataformatInputOutputDropDownLabel.Text = {'Data format'; '(Input/Output)'};
            app.DataformatInputOutputDropDown = uidropdown(app.AES_Encryption);
            app.DataformatInputOutputDropDown.Items = {'Plaintext', 'Hex'};
            app.DataformatInputOutputDropDown.Value = 'Plaintext';
            app.AESModeDropDownLabel = uilabel(app.AES_Encryption);
            app.AESModeDropDownLabel.Text = 'AES Mode';
            app.AESModeDropDown = uidropdown(app.AES_Encryption);
            app.AESModeDropDown.Items = {'ECB', 'CBC'};
            app.AESModeDropDown.ValueChangedFcn = createCallbackFcn(app, @AESModeDropDownValueChanged2, true);
            app.AESModeDropDown.Value = 'ECB';
            % Show the figure after all components are created
            app.AES_Encryption.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AES_Encryption)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AES_Encryption)
        end
    end
end